classdef aliasing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SineAliasDemoUIFigure         matlab.ui.Figure
        Image2                        matlab.ui.control.Image
        Hyperlink                     matlab.ui.control.Hyperlink
        Authors                       matlab.ui.control.Button
        FrequencyHzLabel              matlab.ui.control.Label
        SamplingfrequencyLabel        matlab.ui.control.Label
        NyquistfrequencyLabel         matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
        FrequencySampleRateEditFieldLabel  matlab.ui.control.Label
        FrequencySampleRateEditField  matlab.ui.control.NumericEditField
        SampleRateSliderLabel         matlab.ui.control.Label
        SampleRateSlider              matlab.ui.control.Slider
        DataAquestionLabel            matlab.ui.control.Label
        signalDelayKnob               matlab.ui.control.Knob
        SineAliasDemoLabel            matlab.ui.control.Label
        PointsCycleLabel              matlab.ui.control.Label
        PPC_label                     matlab.ui.control.Label
        ali_stat                      matlab.ui.control.Label
        AliensstatuesLampLabel        matlab.ui.control.Label
        AliensstatuesLamp             matlab.ui.control.Lamp
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
        responseUI                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PROP_signalFreg = 0  % Continuous Signal Frequency
        PROP_sampleFreq = 1 
        PROP_signalType = 0
        PROP_delayFactor = 0
    end
    
    methods (Access = private)
        
        function [] = updateValues(app)
          frqCont = app.PROP_signalFreg ;   % frequency of i/p wave   
          frqSampling = app.PROP_sampleFreq;                  % frequency of samping
          Tsample = 1 / frqSampling ;       % sampling period 
          % Points per Cycle 
          PPC = frqSampling / frqCont ; 
          app.PPC_label.Text = sprintf('%.3f',PPC);
          % check alians 
          if frqCont > (frqSampling/2)
              app.AliensstatuesLamp.Color = [1 0 0];
              app.ali_stat.Text = sprintf("Sin Is Aliased ");
          else
              app.AliensstatuesLamp.Color = [0 1 0];
              app.ali_stat.Text = sprintf("Sin Isn't Aliased "); 
          end
          %Draw Org Wave 
          t_org = 0:0.01:3*pi;
          delay =  2* app.PROP_delayFactor ; 
          y =sin(2*pi*frqCont*(t_org-delay));
          plot(app.responseUI,t_org,y);
          hold(app.responseUI,'on');
          % draw sample wave
          t_sample = 0:Tsample:3*pi;
          y2 = sin(2*pi*frqCont*(t_sample-delay));
          plot(app.responseUI,t_sample,y2,'r--o','MarkerSize',10,'MarkerEdgeColor','r', 'MarkerFaceColor',[0.5,0.5,0.5]);
          legend(app.responseUI, 'Original Sin Wave','Reconstructed Sine Wave','Location','southeast');
          hold(app.responseUI,'off');       
          % Draw Impulse Response 
          freq_response(app,app.PROP_signalFreg);
          hold(app.UIAxes,'on');
          discr_response(app,app.PROP_signalFreg , y2);
          %test(app);
          hold(app.UIAxes,'off');


        end
                
        function [] = freq_response(app,hs_f)
            Fs = 100;            % Sampling frequency, e.g., Hz (samples/sec)                    
            T = 1/Fs;             % Sampling period (time between samples, e.g., sec)
            %L = 100000;             % Length of signal (in samples)
            L = 1000;
            t = (0:L-1)*T;        % Time vector
            % Sine wave parameters
            F = hs_f;
            y = sin(2 * pi * F * t );
            yf = fft(y);        % Fourier transform
            yf = abs(yf/L);     % When X is complex, ABS(X) is the complex modulus (magnitude) of the elements of X
            yf = 2 * yf(1:(L/2)+1);  % Take only the half of frequencies below the nyquist
            f = Fs*(0:(L/2))/L; % define frequencies for each element of yf
            plot(app.UIAxes,f, yf) 
        end
        
        
        function [] = discr_response(app,hs_f,xo2)
            Fs = 1;            % Sampling frequency, e.g., Hz (samples/sec)                    
            L = length(xo2);
            % Length of signal (in samples)
            Tsample = 1 / app.PROP_sampleFreq ; 
            t_sample = 0:Tsample:3*pi;       % Time vector
            % Sine wave parameters
            F = hs_f;
            y = sin(2 * pi * F * t_sample );
            yf = fft(y);        % Fourier transform
            yf = abs(yf/L);     % When X is complex, ABS(X) is the complex modulus (magnitude) of the elements of X
            yf = 2 * yf(1:(L/2)+1);  % Take only the half of frequencies below the nyquist
            f = Fs*(0:(L/2))/L; % define frequencies for each element of yf
            plot(app.UIAxes,f, yf) 


        end
        
        function [] = test(app)
            Fs = 1;            % Sampling frequency, e.g., Hz (samples/sec)                    
            T = 1/Fs;             % Sampling period (time between samples, e.g., sec)
            L = 100000;             % Length of signal (in samples)
            Tsample = 1 / app.PROP_sampleFreq ; 
            t_sample = 0:Tsample:100000*pi;        % Time vector
            % Sine wave parameters
            F = app.PROP_signalFreg;
            y = sin(2 * pi * F * t_sample );
            yf = fft(y);        % Fourier transform
            yf = abs(yf/L);     % When X is complex, ABS(X) is the complex modulus (magnitude) of the elements of X
            yf = 2 * yf(1:(L/2)+1);  % Take only the half of frequencies below the nyquist
            f = Fs*(0:(L/2))/L; % define frequencies for each element of yf
            plot(app.UIAxes,f, yf) 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changing function: SampleRateSlider
        function SampleRateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PROP_signalFreg = changingValue ; 
            app.FrequencySampleRateEditField.Value = changingValue;
            updateValues(app);
        end

        % Value changed function: signalDelayKnob
        function signalDelayKnobValueChanged(app, event)
            value = app.signalDelayKnob.Value;
            app.PROP_delayFactor = value;
            updateValues(app);
        end

        % Value changed function: FrequencySampleRateEditField
        function FrequencySampleRateEditFieldValueChanged(app, event)
            value = app.FrequencySampleRateEditField.Value;
            app.PROP_signalFreg = value ;
            app.SampleRateSlider.Value = value ; 
            updateValues(app);
        end

        % Button pushed function: Authors
        function AuthorsButtonPushed(app, event)
            TeamNames;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SineAliasDemoUIFigure = uifigure('Visible', 'off');
            app.SineAliasDemoUIFigure.Name = 'Sine Alias Demo';
            app.responseUI = uiaxes(app.SineAliasDemoUIFigure);
            title(app.responseUI, 'Sine Spactrum Demo')
            xlabel(app.responseUI, 't')
            ylabel(app.responseUI, 'f(t)')
            app.UIAxes = uiaxes(app.SineAliasDemoUIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XLim = [0 2];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'; '1.2'; '1.3'; '1.4'; '1.5'; '1.6'; '1.7'; '1.8'; '1.9'; '2'};
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.SineAliasDemoUIFigure);
            app.Image.ImageSource = 'a2.png';
            app.AliensstatuesLamp = uilamp(app.SineAliasDemoUIFigure);
            app.AliensstatuesLampLabel = uilabel(app.SineAliasDemoUIFigure);
            app.AliensstatuesLampLabel.Text = 'Aliens statues';
            app.ali_stat = uilabel(app.SineAliasDemoUIFigure);
            app.ali_stat.Text = {''; ''};
            app.PPC_label = uilabel(app.SineAliasDemoUIFigure);
            app.PPC_label.Text = {'Null'; ''};
            app.PointsCycleLabel = uilabel(app.SineAliasDemoUIFigure);
            app.PointsCycleLabel.Text = 'Points/Cycle = ';
            app.SineAliasDemoLabel = uilabel(app.SineAliasDemoUIFigure);
            app.SineAliasDemoLabel.Text = 'Sine Alias Demo';
            app.signalDelayKnob = uiknob(app.SineAliasDemoUIFigure, 'continuous');
            app.signalDelayKnob.Limits = [0 1];
            app.signalDelayKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.signalDelayKnob.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.signalDelayKnob.ValueChangedFcn = createCallbackFcn(app, @signalDelayKnobValueChanged, true);
            app.DataAquestionLabel = uilabel(app.SineAliasDemoUIFigure);
            app.DataAquestionLabel.Text = 'Data Aquestion';
            app.SampleRateSlider = uislider(app.SineAliasDemoUIFigure);
            app.SampleRateSlider.Limits = [0 2];
            app.SampleRateSlider.ValueChangingFcn = createCallbackFcn(app, @SampleRateSliderValueChanging, true);
            app.SampleRateSliderLabel = uilabel(app.SineAliasDemoUIFigure);
            app.SampleRateSliderLabel.Text = 'Sample Rate';
            app.FrequencySampleRateEditField = uieditfield(app.SineAliasDemoUIFigure, 'numeric');
            app.FrequencySampleRateEditField.Limits = [0 2];
            app.FrequencySampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencySampleRateEditFieldValueChanged, true);
            app.FrequencySampleRateEditFieldLabel = uilabel(app.SineAliasDemoUIFigure);
            app.FrequencySampleRateEditFieldLabel.Text = 'Frequency/Sample Rate';
            app.Image_2 = uiimage(app.SineAliasDemoUIFigure);
            app.Image_2.ImageSource = 'a3.png';
            app.NyquistfrequencyLabel = uilabel(app.SineAliasDemoUIFigure);
            app.NyquistfrequencyLabel.Text = 'Nyquist frequency';
            app.SamplingfrequencyLabel = uilabel(app.SineAliasDemoUIFigure);
            app.SamplingfrequencyLabel.Text = 'Sampling frequency';
            app.FrequencyHzLabel = uilabel(app.SineAliasDemoUIFigure);
            app.FrequencyHzLabel.Text = 'Frequency(Hz)';
            app.Authors = uibutton(app.SineAliasDemoUIFigure, 'push');
            app.Authors.ButtonPushedFcn = createCallbackFcn(app, @AuthorsButtonPushed, true);
            app.Authors.Text = 'Authors';
            app.Hyperlink = uihyperlink(app.SineAliasDemoUIFigure);
            app.Hyperlink.URL = 'https://youtu.be/9ecKP3k1kUY';
            app.Hyperlink.Text = 'Youtube Demo';
            app.Image2 = uiimage(app.SineAliasDemoUIFigure);
            app.Image2.ImageSource = 'asss.png';
            % Show the figure after all components are created
            app.SineAliasDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aliasing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SineAliasDemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SineAliasDemoUIFigure)
        end
    end
end