classdef firstOrderSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FirstOrderSystemUIFigure        matlab.ui.Figure
        GotosystemselectionButton       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InfoTab                         matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        PIControllerLabel               matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FirstOrderSystemTransfereFunctionLabel  matlab.ui.control.Label
        SystemInputsWithoutControllerTab  matlab.ui.container.Tab
        UnityStepInputLabel_2           matlab.ui.control.Label
        AllFirstOrderSystemareStableLabel  matlab.ui.control.Label
        FirstOrderCharacteristicsPanel  matlab.ui.container.Panel
        SelectedTimeSec                 matlab.ui.control.EditField
        SelectedTimeSecEditFieldLabel   matlab.ui.control.Label
        TimeConstantTSliderLabel        matlab.ui.control.Label
        TimeConstantTSlider             matlab.ui.control.Slider
        DCGainValueKLabel               matlab.ui.control.Label
        GainValue                       matlab.ui.control.EditField
        Image2_2                        matlab.ui.control.Image
        ForUnityFeedbackLabel           matlab.ui.control.Label
        stepResponseAxis                matlab.ui.control.UIAxes
        ControllerTab                   matlab.ui.container.Tab
        UnityStepInputLabel             matlab.ui.control.Label
        ImpovedTimeConstantT0Label      matlab.ui.control.Label
        txtStability                    matlab.ui.control.Label
        SystemStabilityLamp             matlab.ui.control.Lamp
        SystemStabilityLampLabel        matlab.ui.control.Label
        PITunningPanel                  matlab.ui.container.Panel
        IValue                          matlab.ui.control.NumericEditField
        IValueEditFieldLabel            matlab.ui.control.Label
        PValue                          matlab.ui.control.NumericEditField
        PValueEditFieldLabel            matlab.ui.control.Label
        IntegralSlider                  matlab.ui.control.Slider
        IntegralSliderLabel             matlab.ui.control.Label
        ProportionalSlider              matlab.ui.control.Slider
        ProportionalSliderLabel         matlab.ui.control.Label
        FirstOrderCharacteristicsPanel_2  matlab.ui.container.Panel
        SelectedTimeSec_PID             matlab.ui.control.EditField
        SelectedTimeSecEditFieldLabel_2  matlab.ui.control.Label
        TimeConstantTSlider_2Label      matlab.ui.control.Label
        TimeConstantTSlider_PID         matlab.ui.control.Slider
        DCGainValueKLabel_2             matlab.ui.control.Label
        GainValue_PID                   matlab.ui.control.EditField
        stability                       matlab.ui.control.UIAxes
        PID_Step                        matlab.ui.control.UIAxes
        AutomaticTunningTab             matlab.ui.container.Tab
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        openlooptimeconstant            matlab.ui.control.NumericEditField
        openlooptimeconstantEditFieldLabel  matlab.ui.control.Label
        DesiredTo                       matlab.ui.control.NumericEditField
        DesiredToEditFieldLabel         matlab.ui.control.Label
        Gain                            matlab.ui.control.Slider
        GainLabel                       matlab.ui.control.Label
        Ti                              matlab.ui.control.Label
        K                               matlab.ui.control.Label
        TiLabel                         matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        PI_Ti                           matlab.ui.control.Label
        storderstepresponseLabel        matlab.ui.control.Label
        first_order_pi_plot             matlab.ui.control.UIAxes
        first_order_plot                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stepInputAmp = 1 ;
        Prop_DC_GAIN = 0; % DC GAIN Value
        Prop_TimeConstant = 0; %Time Constant
        Prop_DC_GAIN_PID = 0 ; 
        Prop_TimeConstant_PID = 0; %Time Constant
        Prop_P_Controller = 1 ; 
        Prop_I_Controller = 0 ; 
        Prop_D_Controller = 0 ; 
    end
    
    methods (Access = private)
        
        function [] = firstOrderSystemResponse(app)
            % systemTF =  K / (Ts +1)
            systemTF = tf([app.Prop_DC_GAIN],[app.Prop_TimeConstant 1]) ;
            % Plot  
            plot(app.stepResponseAxis,step(systemTF));
            app.stepResponseAxis.XAxisLocation = 'origin';
            %app.stepResponseAxis.XLim = ([0,100]); 
            plot(app.PZMap,pzmap(systemTF),'x' , "Color",'red'); 
        end
        
        function [] = firstOrderSystemResponse_PID(app)
            % systemTF =  K / (Ts +1)
            systemTF = tf([app.Prop_DC_GAIN_PID],[app.Prop_TimeConstant_PID 1]) ;
            %plot(app.PID_Step,step(systemTF));
            PID = pid(app.Prop_P_Controller , app.Prop_I_Controller,app.Prop_D_Controller);
            closedLoop = feedback(series(systemTF,PID) ,1); 
            %Plot PID
            plot(app.PID_Step,app.stepInputAmp * step(closedLoop));
            hold(app.PID_Step,'on')
            plot(app.PID_Step,step(systemTF),'r--');
            hold(app.PID_Step,'off')
            legend(app.PID_Step, 'PI Response','Step Response of Planet','Location','southeast');
            %PLOT ZP MAP
            plot(app.stability,pzmap(closedLoop),'x' , "Color",'red'); 
            % Improved Time Constant K = T/(G *T0)
            improvedTimeConstant = app.Prop_TimeConstant_PID / (app.Prop_P_Controller * app.stepInputAmp); % 1 for Unity step input
            app.ImpovedTimeConstantT0Label.Text = sprintf("Improved Time Constant = %f",improvedTimeConstant);
            %Stability
            app.stability.XAxisLocation = 'origin';
            app.PID_Step.XAxisLocation = 'origin';
            
            % Stability
           if isstable (closedLoop)
               app.SystemStabilityLamp.Color = [0 1 0];
               app.txtStability.Text = sprintf("System Is Stable");
           else
               app.SystemStabilityLamp.Color = [1 0 0];
              app.txtStability.Text = sprintf("System Is Unstable");

           end  
            
        end
                
        function [] = responses(app)   
            time_response = @(t) app.Gain.Value*(1-exp(-(t/app.openlooptimeconstant.Value)));
            fplot(app.first_order_plot,time_response);
            
            
            %for the pi controller 
           % PI_controller = app.Pi_K.Value *( 1 + tf( [1] , [app.first_order_timeConstant.Value 0] ) )
             PI_transfer_fn = @(t) app.Gain.Value*(1-exp(-(t/app.DesiredTo.Value)));
             fplot(app.first_order_pi_plot,PI_transfer_fn);
            
            % in PI , K = T / G To & To is the new time constant
            app.K.Text = sprintf('%f',app.openlooptimeconstant.Value/(app.Gain.Value*app.DesiredTo.Value));
            app.Ti.Text = sprintf('%f',app.openlooptimeconstant.Value);
           
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           firstOrderSystemResponse(app);
           firstOrderSystemResponse_PID(app);
        end

        % Button pushed function: GotosystemselectionButton
        function GotosystemselectionButtonPushed(app, event)
            startScreen;
           app.delete;
        end

        % Value changed function: GainValue
        function GainValueValueChanged(app, event)
            app.Prop_DC_GAIN = str2double(app.GainValue.Value);
            firstOrderSystemResponse(app);
        end

        % Value changed function: TimeConstantTSlider
        function TimeConstantTSliderValueChanged(app, event)
            app.Prop_TimeConstant = app.TimeConstantTSlider.Value;
            app.SelectedTimeSec.Value = num2str(app.Prop_TimeConstant);
            firstOrderSystemResponse(app);
        end

        % Value changed function: SelectedTimeSec
        function SelectedTimeSecValueChanged(app, event)
            app.Prop_TimeConstant = str2double(app.SelectedTimeSec.Value);
            app.TimeConstantTSlider.Value = app.Prop_TimeConstant;
            firstOrderSystemResponse(app);
        end

        % Value changed function: GainValue_PID
        function GainValue_PIDValueChanged(app, event)
            value = app.GainValue_PID.Value;
            app.Prop_DC_GAIN_PID = str2double(value) ;
            firstOrderSystemResponse_PID(app);
        end

        % Value changed function: TimeConstantTSlider_PID
        function TimeConstantTSlider_PIDValueChanged(app, event)
            value = app.TimeConstantTSlider_PID.Value;
            app.Prop_TimeConstant_PID =value ; 
            app.SelectedTimeSec_PID.Value = num2str(value) ;
           firstOrderSystemResponse_PID(app);

        end

        % Value changed function: SelectedTimeSec_PID
        function SelectedTimeSec_PIDValueChanged(app, event)
            value = app.SelectedTimeSec_PID.Value;
            app.Prop_TimeConstant_PID =str2double(value) ; 
            app.TimeConstantTSlider_PID.Value = str2double(value) ;
            firstOrderSystemResponse_PID(app);

        end

        % Value changed function: ProportionalSlider
        function ProportionalSliderValueChanged(app, event)
            value = app.ProportionalSlider.Value;
            app.Prop_P_Controller = value ; 
            app.PValue.Value = value ; 
            firstOrderSystemResponse_PID(app);
        end

        % Value changed function: PValue
        function PValueValueChanged(app, event)
            value = app.PValue.Value;
            app.Prop_P_Controller = value ; 
            app.ProportionalSlider.Value = value ; 
            firstOrderSystemResponse_PID(app);

        end

        % Value changed function: IntegralSlider
        function IntegralSliderValueChanged(app, event)
            value = app.IntegralSlider.Value;
            app.Prop_I_Controller = value ; 
            app.IValue.Value = value ;
            firstOrderSystemResponse_PID(app);

        end

        % Value changed function: IValue
        function IValueValueChanged(app, event)
            value = app.IValue.Value;
            app.Prop_I_Controller = value ;
            app.IntegralSlider.Value = value;
            firstOrderSystemResponse_PID(app);
            
        end

        % Value changed function: openlooptimeconstant
        function openlooptimeconstantValueChanged(app, event)
            value = app.openlooptimeconstant.Value;
            responses(app);
        end

        % Value changed function: Gain
        function GainValueChanged(app, event)
            value = app.Gain.Value;
            responses(app);
        end

        % Value changed function: DesiredTo
        function DesiredToValueChanged(app, event)
            value = app.DesiredTo.Value;
            responses(app);
        end

        % Value changing function: Gain
        function GainValueChanging(app, event)
            changingValue = event.Value;
            responses(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FirstOrderSystemUIFigure = uifigure('Visible', 'off');
            app.FirstOrderSystemUIFigure.Name = 'First Order System';
            app.FirstOrderSystemUIFigure.Icon = 'graph.png';
            app.TabGroup = uitabgroup(app.FirstOrderSystemUIFigure);
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.FirstOrderSystemTransfereFunctionLabel = uilabel(app.InfoTab);
            app.FirstOrderSystemTransfereFunctionLabel.Text = 'First Order System Transfere Function ';
            app.Image = uiimage(app.InfoTab);
            app.Image.ImageSource = 'Capture.PNG';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'Where:'; ''; '   - K is the DC Gain (DC gain of the system ratio between the input signal and the steady-state value of output)'; '   - T is the time constant of the system (the time constant is a measure of how quickly a first-order system '; '       responds to a unit step input '; '   - Unity Feedback'};
            app.Image2 = uiimage(app.InfoTab);
            app.Image2.ImageSource = '1stOrderTF.PNG';
            app.PIControllerLabel = uilabel(app.InfoTab);
            app.PIControllerLabel.Text = 'PI - Controller ';
            app.Image3 = uiimage(app.InfoTab);
            app.Image3.ImageSource = 'genericModel.jpg';
            app.Image4 = uiimage(app.InfoTab);
            app.Image4.ImageSource = 'aa.png';
            app.SystemInputsWithoutControllerTab = uitab(app.TabGroup);
            app.SystemInputsWithoutControllerTab.Title = 'System Inputs Without Controller';
            app.stepResponseAxis = uiaxes(app.SystemInputsWithoutControllerTab);
            title(app.stepResponseAxis, 'Step Response')
            xlabel(app.stepResponseAxis, 'Time (sec)')
            ylabel(app.stepResponseAxis, 'F(t)')
            app.ForUnityFeedbackLabel = uilabel(app.SystemInputsWithoutControllerTab);
            app.ForUnityFeedbackLabel.Text = 'For Unity Feedback ';
            app.Image2_2 = uiimage(app.SystemInputsWithoutControllerTab);
            app.Image2_2.ImageSource = '1stOrderTF.PNG';
            app.FirstOrderCharacteristicsPanel = uipanel(app.SystemInputsWithoutControllerTab);
            app.FirstOrderCharacteristicsPanel.Title = 'First Order Characteristics';
            app.GainValue = uieditfield(app.FirstOrderCharacteristicsPanel, 'text');
            app.GainValue.ValueChangedFcn = createCallbackFcn(app, @GainValueValueChanged, true);
            app.DCGainValueKLabel = uilabel(app.FirstOrderCharacteristicsPanel);
            app.DCGainValueKLabel.Text = 'DC Gain Value (K)';
            app.TimeConstantTSlider = uislider(app.FirstOrderCharacteristicsPanel);
            app.TimeConstantTSlider.ValueChangedFcn = createCallbackFcn(app, @TimeConstantTSliderValueChanged, true);
            app.TimeConstantTSliderLabel = uilabel(app.FirstOrderCharacteristicsPanel);
            app.TimeConstantTSliderLabel.Text = 'Time Constant (T)';
            app.SelectedTimeSecEditFieldLabel = uilabel(app.FirstOrderCharacteristicsPanel);
            app.SelectedTimeSecEditFieldLabel.Text = 'Selected Time (Sec)';
            app.SelectedTimeSec = uieditfield(app.FirstOrderCharacteristicsPanel, 'text');
            app.SelectedTimeSec.ValueChangedFcn = createCallbackFcn(app, @SelectedTimeSecValueChanged, true);
            app.AllFirstOrderSystemareStableLabel = uilabel(app.SystemInputsWithoutControllerTab);
            app.AllFirstOrderSystemareStableLabel.Text = 'All First Order System are Stable';
            app.UnityStepInputLabel_2 = uilabel(app.SystemInputsWithoutControllerTab);
            app.UnityStepInputLabel_2.Text = 'Unity Step Input';
            app.ControllerTab = uitab(app.TabGroup);
            app.ControllerTab.Title = 'Controller ';
            app.PID_Step = uiaxes(app.ControllerTab);
            title(app.PID_Step, 'Step Response ')
            xlabel(app.PID_Step, 'Time (Sec)')
            ylabel(app.PID_Step, 'F(t)')
            zlabel(app.PID_Step, 'Z')
            app.stability = uiaxes(app.ControllerTab);
            title(app.stability, 'Zero-Pole Map')
            xlabel(app.stability, 'σ')
            ylabel(app.stability, 'Jw')
            zlabel(app.stability, 'Z')
            app.FirstOrderCharacteristicsPanel_2 = uipanel(app.ControllerTab);
            app.FirstOrderCharacteristicsPanel_2.Title = 'First Order Characteristics';
            app.GainValue_PID = uieditfield(app.FirstOrderCharacteristicsPanel_2, 'text');
            app.GainValue_PID.ValueChangedFcn = createCallbackFcn(app, @GainValue_PIDValueChanged, true);
            app.DCGainValueKLabel_2 = uilabel(app.FirstOrderCharacteristicsPanel_2);
            app.DCGainValueKLabel_2.Text = 'DC Gain Value (K)';
            app.TimeConstantTSlider_PID = uislider(app.FirstOrderCharacteristicsPanel_2);
            app.TimeConstantTSlider_PID.ValueChangedFcn = createCallbackFcn(app, @TimeConstantTSlider_PIDValueChanged, true);
            app.TimeConstantTSlider_2Label = uilabel(app.FirstOrderCharacteristicsPanel_2);
            app.TimeConstantTSlider_2Label.Text = 'Time Constant (T)';
            app.SelectedTimeSecEditFieldLabel_2 = uilabel(app.FirstOrderCharacteristicsPanel_2);
            app.SelectedTimeSecEditFieldLabel_2.Text = 'Selected Time (Sec)';
            app.SelectedTimeSec_PID = uieditfield(app.FirstOrderCharacteristicsPanel_2, 'text');
            app.SelectedTimeSec_PID.ValueChangedFcn = createCallbackFcn(app, @SelectedTimeSec_PIDValueChanged, true);
            app.PITunningPanel = uipanel(app.ControllerTab);
            app.PITunningPanel.Title = 'PI Tunning';
            app.ProportionalSliderLabel = uilabel(app.PITunningPanel);
            app.ProportionalSliderLabel.Text = 'Proportional';
            app.ProportionalSlider = uislider(app.PITunningPanel);
            app.ProportionalSlider.Limits = [-10 100];
            app.ProportionalSlider.ValueChangedFcn = createCallbackFcn(app, @ProportionalSliderValueChanged, true);
            app.IntegralSliderLabel = uilabel(app.PITunningPanel);
            app.IntegralSliderLabel.Text = 'Integral';
            app.IntegralSlider = uislider(app.PITunningPanel);
            app.IntegralSlider.Limits = [-10 100];
            app.IntegralSlider.ValueChangedFcn = createCallbackFcn(app, @IntegralSliderValueChanged, true);
            app.PValueEditFieldLabel = uilabel(app.PITunningPanel);
            app.PValueEditFieldLabel.Text = 'P-Value';
            app.PValue = uieditfield(app.PITunningPanel, 'numeric');
            app.PValue.ValueChangedFcn = createCallbackFcn(app, @PValueValueChanged, true);
            app.PValue.Value = 1;
            app.IValueEditFieldLabel = uilabel(app.PITunningPanel);
            app.IValueEditFieldLabel.Text = 'I-Value';
            app.IValue = uieditfield(app.PITunningPanel, 'numeric');
            app.IValue.ValueChangedFcn = createCallbackFcn(app, @IValueValueChanged, true);
            app.SystemStabilityLampLabel = uilabel(app.ControllerTab);
            app.SystemStabilityLampLabel.Text = 'System Stability';
            app.SystemStabilityLamp = uilamp(app.ControllerTab);
            app.txtStability = uilabel(app.ControllerTab);
            app.txtStability.Text = '';
            app.ImpovedTimeConstantT0Label = uilabel(app.ControllerTab);
            app.ImpovedTimeConstantT0Label.Text = 'Impoved Time Constant (T0)';
            app.UnityStepInputLabel = uilabel(app.ControllerTab);
            app.UnityStepInputLabel.Text = 'Unity Step Input';
            app.AutomaticTunningTab = uitab(app.TabGroup);
            app.AutomaticTunningTab.Title = 'AutomaticTunning';
            app.first_order_plot = uiaxes(app.AutomaticTunningTab);
            title(app.first_order_plot, 'open loop ')
            xlabel(app.first_order_plot, 'time')
            ylabel(app.first_order_plot, 'F(t)')
            app.first_order_plot.XLim = [0 300];
            app.first_order_plot.YLim = [0 30];
            app.first_order_pi_plot = uiaxes(app.AutomaticTunningTab);
            title(app.first_order_pi_plot, 'pi controller')
            xlabel(app.first_order_pi_plot, 't')
            ylabel(app.first_order_pi_plot, 'F(t)')
            app.first_order_pi_plot.XLim = [0 300];
            app.first_order_pi_plot.YLim = [0 30];
            app.storderstepresponseLabel = uilabel(app.AutomaticTunningTab);
            app.storderstepresponseLabel.Text = '1st order - step response';
            app.PI_Ti = uilabel(app.AutomaticTunningTab);
            app.PI_Ti.Text = ' ';
            app.KLabel = uilabel(app.AutomaticTunningTab);
            app.KLabel.Text = 'K = ';
            app.TiLabel = uilabel(app.AutomaticTunningTab);
            app.TiLabel.Text = 'Ti = ';
            app.K = uilabel(app.AutomaticTunningTab);
            app.K.Text = ' ';
            app.Ti = uilabel(app.AutomaticTunningTab);
            app.Ti.Text = ' ';
            app.GainLabel = uilabel(app.AutomaticTunningTab);
            app.GainLabel.Text = 'Gain';
            app.Gain = uislider(app.AutomaticTunningTab);
            app.Gain.Limits = [1 30];
            app.Gain.ValueChangedFcn = createCallbackFcn(app, @GainValueChanged, true);
            app.Gain.ValueChangingFcn = createCallbackFcn(app, @GainValueChanging, true);
            app.Gain.Value = 1;
            app.DesiredToEditFieldLabel = uilabel(app.AutomaticTunningTab);
            app.DesiredToEditFieldLabel.Text = 'Desired To = ';
            app.DesiredTo = uieditfield(app.AutomaticTunningTab, 'numeric');
            app.DesiredTo.ValueChangedFcn = createCallbackFcn(app, @DesiredToValueChanged, true);
            app.openlooptimeconstantEditFieldLabel = uilabel(app.AutomaticTunningTab);
            app.openlooptimeconstantEditFieldLabel.Text = 'open loop time constant';
            app.openlooptimeconstant = uieditfield(app.AutomaticTunningTab, 'numeric');
            app.openlooptimeconstant.ValueChangedFcn = createCallbackFcn(app, @openlooptimeconstantValueChanged, true);
            app.Image5 = uiimage(app.AutomaticTunningTab);
            app.Image5.ImageSource = 'plant.jpg';
            app.Image6 = uiimage(app.AutomaticTunningTab);
            app.Image6.ImageSource = 'pi.jpg';
            app.GotosystemselectionButton = uibutton(app.FirstOrderSystemUIFigure, 'push');
            app.GotosystemselectionButton.ButtonPushedFcn = createCallbackFcn(app, @GotosystemselectionButtonPushed, true);
            app.GotosystemselectionButton.Text = 'Go to system selection';
            % Show the figure after all components are created
            app.FirstOrderSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = firstOrderSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FirstOrderSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FirstOrderSystemUIFigure)
        end
    end
end