classdef secondOrderSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InfoTab                        matlab.ui.container.Tab
        CriticaldampedLabel            matlab.ui.control.Label
        UnderdampedLabel               matlab.ui.control.Label
        UndampedLabel                  matlab.ui.control.Label
        OverdampedLabel                matlab.ui.control.Label
        Image2_5                       matlab.ui.control.Image
        Image2_4                       matlab.ui.control.Image
        Image2_2                       matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Label                          matlab.ui.control.Label
        WnNaturalFrequencyZetaDampingRatioLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SecondOrderSystemLabel         matlab.ui.control.Label
        SecondSystemOrderTab           matlab.ui.container.Tab
        SystemStabilityLamp            matlab.ui.control.Lamp
        SystemStabilityLampLabel       matlab.ui.control.Label
        txtStability                   matlab.ui.control.Label
        SecondOrderCharacteristicsPanel  matlab.ui.container.Panel
        systemType_IP                  matlab.ui.control.Label
        SystemTypeLabel                matlab.ui.control.Label
        oversoot_IP                    matlab.ui.control.Label
        TimeConstant_IP                matlab.ui.control.Label
        settlingTime_IP                matlab.ui.control.Label
        raiseTime_IP                   matlab.ui.control.Label
        OvershootLabel                 matlab.ui.control.Label
        TimeConstantSecLabel           matlab.ui.control.Label
        SettlingTimeSecLabel           matlab.ui.control.Label
        RaiseTimeSecLabel              matlab.ui.control.Label
        zeta                           matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        DampingRatioSlider             matlab.ui.control.Slider
        DampingRatioSliderLabel        matlab.ui.control.Label
        Wn                             matlab.ui.control.NumericEditField
        WnRadSecEditFieldLabel         matlab.ui.control.Label
        NaturalFrequencySlider         matlab.ui.control.Slider
        NaturalFrequencySliderLabel    matlab.ui.control.Label
        ForUnityFeedbackLabel          matlab.ui.control.Label
        Image2_6                       matlab.ui.control.Image
        PZGraph                        matlab.ui.control.UIAxes
        stepGraph                      matlab.ui.control.UIAxes
        ControllerTab                  matlab.ui.container.Tab
        DesiredWnLabel                 matlab.ui.control.Label
        WnDesired                      matlab.ui.control.Label
        ZetaDesired                    matlab.ui.control.Label
        DesiredZetaLabel               matlab.ui.control.Label
        PIDTunningPanel                matlab.ui.container.Panel
        D_Controller                   matlab.ui.control.NumericEditField
        DControllerEditFieldLabel      matlab.ui.control.Label
        I_Controller                   matlab.ui.control.NumericEditField
        IControllerEditFieldLabel      matlab.ui.control.Label
        P_Controller                   matlab.ui.control.NumericEditField
        PControllerEditFieldLabel      matlab.ui.control.Label
        D_ControllerSlider             matlab.ui.control.Slider
        DControllerSliderLabel         matlab.ui.control.Label
        I_ControllerSlider             matlab.ui.control.Slider
        IControllerSliderLabel         matlab.ui.control.Label
        P_ControllerSlider             matlab.ui.control.Slider
        PControllerSliderLabel         matlab.ui.control.Label
        SecondOrderCharacteristicsPanel_2  matlab.ui.container.Panel
        systemType_IP_PID              matlab.ui.control.Label
        SystemTypeLabel_PID            matlab.ui.control.Label
        oversoot_IP_PID                matlab.ui.control.Label
        TimeConstant_IP_PID            matlab.ui.control.Label
        settlingTime_IP_PID            matlab.ui.control.Label
        raiseTime_IP_PID               matlab.ui.control.Label
        OvershootLabel_PID             matlab.ui.control.Label
        TimeConstantSecLabel_PID       matlab.ui.control.Label
        SettlingTimeSecLabel_PID       matlab.ui.control.Label
        RaiseTimeSecLabel_PID          matlab.ui.control.Label
        zeta_PID                       matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        DampingRatioSlider_PID         matlab.ui.control.Slider
        DampingRatioSlider_2Label      matlab.ui.control.Label
        Wn_PID                         matlab.ui.control.NumericEditField
        WnRadSecEditFieldLabel_2       matlab.ui.control.Label
        NaturalFrequencySlider_PID     matlab.ui.control.Slider
        NaturalFrequencySlider_2Label  matlab.ui.control.Label
        ZPAxis                         matlab.ui.control.UIAxes
        responseGraph                  matlab.ui.control.UIAxes
        AutomaticTab                   matlab.ui.container.Tab
        Image5                         matlab.ui.control.Image
        Ti                             matlab.ui.control.Label
        K                              matlab.ui.control.Label
        Td                             matlab.ui.control.Label
        KLabel                         matlab.ui.control.Label
        TdLabel                        matlab.ui.control.Label
        TiLabel                        matlab.ui.control.Label
        zeta_m                         matlab.ui.control.NumericEditField
        DesiredLabel                   matlab.ui.control.Label
        wo                             matlab.ui.control.NumericEditField
        DesiredWoLabel                 matlab.ui.control.Label
        a2                             matlab.ui.control.NumericEditField
        a2Label                        matlab.ui.control.Label
        a1                             matlab.ui.control.NumericEditField
        a1Label                        matlab.ui.control.Label
        bo                             matlab.ui.control.NumericEditField
        boEditFieldLabel               matlab.ui.control.Label
        ClosedLoopLabel                matlab.ui.control.Label
        OpenLoopLabel                  matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        pid_closed_loop_plot           matlab.ui.control.UIAxes
        secon_order_plot               matlab.ui.control.UIAxes
        GotoSystemSelectionButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Prop_Zeta = 0 ;   % Description
        Prop_Wn = 0 ;
        Prop_Zeta_PID = 1 ;   % Description
        Prop_Wn_PID = 1 ;
        Prop_P_Controller = 1 ; 
        Prop_I_Controller = 0 ; 
        Prop_D_Controller = 0 ; 
    end
    
    methods (Access = private)
        
        function [] = secondOrderResponse(app)
           sys=tf([(app.Prop_Wn^2)],[1 2*app.Prop_Zeta*app.Prop_Wn app.Prop_Wn^2]);
            plot(app.stepGraph,step(sys));
            plot(app.PZGraph,pzmap(sys),'x');
            app.stepGraph.XAxisLocation = 'origin';
            beta=acos(abs(app.Prop_Zeta));
            wd=app.Prop_Wn*sqrt(1-app.Prop_Zeta^2);
           if app.Prop_Zeta >= 0 && app.Prop_Zeta <= 1
               app.raiseTime_IP.Text= sprintf("%f", (pi-beta)/wd);
               app.settlingTime_IP.Text = sprintf("%f", 4/(app.Prop_Zeta*app.Prop_Wn));
               app.TimeConstant_IP.Text = sprintf("%f",1/(app.Prop_Zeta*app.Prop_Wn));
               app.oversoot_IP.Text = sprintf("%f",exp((-pi*app.Prop_Zeta)/sqrt(1-app.Prop_Zeta))*100);
               app.systemType_IP.Text = sprintf("Underdamped");
           else
               app.raiseTime_IP.Text= sprintf("NA");
               app.settlingTime_IP.Text = sprintf("NA");
               app.TimeConstant_IP.Text = sprintf("NA");
               app.oversoot_IP.Text = sprintf("NA");
               app.systemType_IP.Text = sprintf("NA");
           end
        if isstable (sys)
           app.SystemStabilityLamp.Color = [0 1 0];
           app.txtStability.Text = sprintf("System Is Stable");
       else
           app.SystemStabilityLamp.Color = [1 0 0];
          app.txtStability.Text = sprintf("System Is Unstable");

       end

        end
       
        
        function [] = secondOrderResponse_PID(app)
           sys=tf([(app.Prop_Wn_PID^2)],[1 2*app.Prop_Zeta_PID*app.Prop_Wn_PID app.Prop_Wn_PID^2]);
           PID = pid(app.Prop_P_Controller,app.Prop_I_Controller,app.Prop_D_Controller);
           closedLoop = feedback(series(sys,PID) ,1); 
           plot(app.responseGraph,step(closedLoop));
           hold(app.responseGraph,'on');
           plot(app.responseGraph,step(sys),'r--');
           hold(app.responseGraph,'off');
           legend(app.responseGraph, 'PID Response','Step Response of Planet','Location','southeast');
           [DesierdWn,Desiredzeta] = damp(closedLoop);
           app.WnDesired.Text = sprintf("%f",DesierdWn);
           app.ZetaDesired.Text = sprintf("%f",Desiredzeta);
           sysInfo = stepinfo(closedLoop); 
           app.raiseTime_IP_PID.Text = sprintf("%f",sysInfo.RiseTime);
           app.settlingTime_IP_PID.Text = sprintf("%f",sysInfo.SettlingTime);
           app.oversoot_IP_PID.Text = sprintf("%f",sysInfo.Overshoot);

        end
        
        
        function [] = response(app)
            sys = tf([app.bo.Value],[app.a2.Value app.a1.Value 1]);
            plot(app.secon_order_plot,step(sys));
            Hcl=tf([(app.wo.Value)^2],[1 2*app.zeta_m.Value*app.wo.Value (app.wo.Value)^2]);
            plot(app.pid_closed_loop_plot,step(Hcl));
            %values of pid
            N_value = 2*app.zeta_m.Value*app.wo.Value;
            Ti_value= app.a1.Value - 1/N_value;
            Td_value= (app.a2.Value/Ti_value)-(1/N_value);
            K_value = (app.wo.Value*Ti_value)/(2*app.zeta_m.Value*app.bo.Value);
            
            app.Ti.Text = sprintf("%f",Ti_value);
            app.Td.Text = sprintf("%f",Td_value);
            app.K.Text = sprintf("%f",K_value);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GotoSystemSelectionButton
        function GotoSystemSelectionButtonPushed(app, event)
            startScreen ;
            app.delete ; 

        end

        % Value changed function: NaturalFrequencySlider
        function NaturalFrequencySliderValueChanged(app, event)
            value = app.NaturalFrequencySlider.Value;
            app.Prop_Wn = value ; 
            app.Wn.Value = value ;
            secondOrderResponse(app);
        end

        % Value changed function: Wn
        function WnValueChanged(app, event)
            value = app.Wn.Value;
            app.Prop_Wn = value ; 
            app.NaturalFrequencySlider.Value = value ; 
            secondOrderResponse(app);
        end

        % Value changed function: DampingRatioSlider
        function DampingRatioSliderValueChanged(app, event)
            value = app.DampingRatioSlider.Value;
            app.Prop_Zeta = value ;
            app.zeta.Value = value ; 
            secondOrderResponse(app);
        end

        % Value changed function: zeta
        function zetaValueChanged(app, event)
            value = app.zeta.Value;
            app.Prop_Zeta = value ;
            app.DampingRatioSlider.Value = value ; 
            secondOrderResponse(app);
        end

        % Value changed function: NaturalFrequencySlider_PID
        function NaturalFrequencySlider_PIDValueChanged(app, event)
            value = app.NaturalFrequencySlider_PID.Value;
            app.Prop_Zeta_PID = value ; 
            app.Wn_PID.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: Wn_PID
        function Wn_PIDValueChanged(app, event)
            value = app.Wn_PID.Value;
            app.Prop_Zeta_PID = value ; 
            app.NaturalFrequencySlider_PID.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: DampingRatioSlider_PID
        function DampingRatioSlider_PIDValueChanged(app, event)
            value = app.DampingRatioSlider_PID.Value;
            app.Prop_Zeta_PID = value ; 
            app.zeta_PID.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: zeta_PID
        function zeta_PIDValueChanged(app, event)
            value = app.zeta_PID.Value;
            app.Prop_Zeta_PID = value ; 
            app.DampingRatioSlider_PID.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: P_ControllerSlider
        function P_ControllerSliderValueChanged(app, event)
            value = app.P_ControllerSlider.Value;
            app.Prop_P_Controller = value ; 
            app.P_Controller.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: I_ControllerSlider
        function I_ControllerSliderValueChanged(app, event)
            value = app.I_ControllerSlider.Value;
            app.Prop_I_Controller = value ; 
            app.I_Controller.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: D_ControllerSlider
        function D_ControllerSliderValueChanged(app, event)
            value = app.D_ControllerSlider.Value;
            app.Prop_D_Controller = value ; 
            app.D_Controller.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: P_Controller
        function P_ControllerValueChanged(app, event)
            value = app.P_Controller.Value;
            app.Prop_P_Controller = value ; 
            app.P_ControllerSlider.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: I_Controller
        function I_ControllerValueChanged(app, event)
            value = app.I_Controller.Value;
            app.Prop_I_Controller = value ; 
            app.I_ControllerSlider.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: D_Controller
        function D_ControllerValueChanged(app, event)
            value = app.D_Controller.Value;
            app.Prop_D_Controller = value ; 
            app.D_ControllerSlider.Value = value ; 
            secondOrderResponse_PID(app);
        end

        % Value changed function: bo
        function boValueChanged(app, event)
            value = app.bo.Value;
            response(app);
        end

        % Value changed function: a1
        function a1ValueChanged(app, event)
            value = app.a1.Value;
            response(app);
        end

        % Value changed function: a2
        function a2ValueChanged(app, event)
            value = app.a2.Value;
            response(app);
        end

        % Value changed function: wo
        function woValueChanged(app, event)
            value = app.wo.Value;
            response(app);
        end

        % Value changed function: zeta_m
        function zeta_mValueChanged(app, event)
            value = app.zeta_m.Value;
            response(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GotoSystemSelectionButton = uibutton(app.UIFigure, 'push');
            app.GotoSystemSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @GotoSystemSelectionButtonPushed, true);
            app.GotoSystemSelectionButton.Text = 'Go to System Selection ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.SecondOrderSystemLabel = uilabel(app.InfoTab);
            app.SecondOrderSystemLabel.Text = 'Second Order System';
            app.Image = uiimage(app.InfoTab);
            app.Image.ImageSource = 'a.PNG';
            app.WnNaturalFrequencyZetaDampingRatioLabel = uilabel(app.InfoTab);
            app.WnNaturalFrequencyZetaDampingRatioLabel.Text = {'Wn = Natural Frequency '; 'Zeta = Damping Ratio'; 'ζ is the damping ratio:'; ''; '  '};
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'    If ζ > 1, then both poles are negative and real. The system is overdamped.'; '    If ζ = 1, then both poles are equal, negative, and real (s = -ωn). The system is critically damped.'; '    If 0 < ζ < 1, then poles are complex conjugates with negative real part. . The system is underdamped.'; '    If ζ = 0, then both poles are imaginary and complex conjugate s = +/-jωn. The system is undamped.'; '    If ζ < 0, then both poles are in the right half of the Laplace plane. The system is unstable.'};
            app.Image2 = uiimage(app.InfoTab);
            app.Image2.ImageSource = 'OverDamed.PNG';
            app.Image2_2 = uiimage(app.InfoTab);
            app.Image2_2.ImageSource = 'undamped.PNG';
            app.Image2_4 = uiimage(app.InfoTab);
            app.Image2_4.ImageSource = 'Under Damped.PNG';
            app.Image2_5 = uiimage(app.InfoTab);
            app.Image2_5.ImageSource = 'critical damped.PNG';
            app.OverdampedLabel = uilabel(app.InfoTab);
            app.OverdampedLabel.Text = 'Overdamped';
            app.UndampedLabel = uilabel(app.InfoTab);
            app.UndampedLabel.Text = 'Undamped';
            app.UnderdampedLabel = uilabel(app.InfoTab);
            app.UnderdampedLabel.Text = 'Underdamped';
            app.CriticaldampedLabel = uilabel(app.InfoTab);
            app.CriticaldampedLabel.Text = 'Criticaldamped';
            app.SecondSystemOrderTab = uitab(app.TabGroup);
            app.SecondSystemOrderTab.Title = 'Second System Order';
            app.stepGraph = uiaxes(app.SecondSystemOrderTab);
            title(app.stepGraph, 'Step Response')
            xlabel(app.stepGraph, 't(sec)')
            ylabel(app.stepGraph, 'F(t)')
            zlabel(app.stepGraph, 'Z')
            app.PZGraph = uiaxes(app.SecondSystemOrderTab);
            title(app.PZGraph, 'Poles-Zeros')
            xlabel(app.PZGraph, 'σ')
            ylabel(app.PZGraph, 'Jw')
            zlabel(app.PZGraph, 'Z')
            app.Image2_6 = uiimage(app.SecondSystemOrderTab);
            app.Image2_6.ImageSource = 'a.PNG';
            app.ForUnityFeedbackLabel = uilabel(app.SecondSystemOrderTab);
            app.ForUnityFeedbackLabel.Text = 'For Unity Feedback ';
            app.SecondOrderCharacteristicsPanel = uipanel(app.SecondSystemOrderTab);
            app.SecondOrderCharacteristicsPanel.Title = 'Second Order Characteristics';
            app.NaturalFrequencySliderLabel = uilabel(app.SecondOrderCharacteristicsPanel);
            app.NaturalFrequencySliderLabel.Text = 'Natural Frequency';
            app.NaturalFrequencySlider = uislider(app.SecondOrderCharacteristicsPanel);
            app.NaturalFrequencySlider.Limits = [0 10];
            app.NaturalFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @NaturalFrequencySliderValueChanged, true);
            app.WnRadSecEditFieldLabel = uilabel(app.SecondOrderCharacteristicsPanel);
            app.WnRadSecEditFieldLabel.Text = 'Wn(Rad/Sec)';
            app.Wn = uieditfield(app.SecondOrderCharacteristicsPanel, 'numeric');
            app.Wn.ValueChangedFcn = createCallbackFcn(app, @WnValueChanged, true);
            app.DampingRatioSliderLabel = uilabel(app.SecondOrderCharacteristicsPanel);
            app.DampingRatioSliderLabel.Text = 'Damping Ratio';
            app.DampingRatioSlider = uislider(app.SecondOrderCharacteristicsPanel);
            app.DampingRatioSlider.Limits = [-2 2];
            app.DampingRatioSlider.ValueChangedFcn = createCallbackFcn(app, @DampingRatioSliderValueChanged, true);
            app.Label_2 = uilabel(app.SecondOrderCharacteristicsPanel);
            app.Label_2.Text = 'ζ';
            app.zeta = uieditfield(app.SecondOrderCharacteristicsPanel, 'numeric');
            app.zeta.ValueChangedFcn = createCallbackFcn(app, @zetaValueChanged, true);
            app.RaiseTimeSecLabel = uilabel(app.SecondOrderCharacteristicsPanel);
            app.RaiseTimeSecLabel.Text = 'Raise Time (Sec)';
            app.SettlingTimeSecLabel = uilabel(app.SecondOrderCharacteristicsPanel);
            app.SettlingTimeSecLabel.Text = 'Settling Time (Sec)';
            app.TimeConstantSecLabel = uilabel(app.SecondOrderCharacteristicsPanel);
            app.TimeConstantSecLabel.Text = 'Time Constant (Sec)';
            app.OvershootLabel = uilabel(app.SecondOrderCharacteristicsPanel);
            app.OvershootLabel.Text = 'Overshoot %';
            app.raiseTime_IP = uilabel(app.SecondOrderCharacteristicsPanel);
            app.raiseTime_IP.Text = 'NaN';
            app.settlingTime_IP = uilabel(app.SecondOrderCharacteristicsPanel);
            app.settlingTime_IP.Text = 'NaN';
            app.TimeConstant_IP = uilabel(app.SecondOrderCharacteristicsPanel);
            app.TimeConstant_IP.Text = 'NaN';
            app.oversoot_IP = uilabel(app.SecondOrderCharacteristicsPanel);
            app.oversoot_IP.Text = 'NaN';
            app.SystemTypeLabel = uilabel(app.SecondOrderCharacteristicsPanel);
            app.SystemTypeLabel.Text = 'System Type';
            app.systemType_IP = uilabel(app.SecondOrderCharacteristicsPanel);
            app.systemType_IP.Text = 'NaN';
            app.txtStability = uilabel(app.SecondSystemOrderTab);
            app.txtStability.Text = '';
            app.SystemStabilityLampLabel = uilabel(app.SecondSystemOrderTab);
            app.SystemStabilityLampLabel.Text = 'System Stability';
            app.SystemStabilityLamp = uilamp(app.SecondSystemOrderTab);
            app.ControllerTab = uitab(app.TabGroup);
            app.ControllerTab.Title = 'Controller';
            app.responseGraph = uiaxes(app.ControllerTab);
            title(app.responseGraph, 'Response')
            xlabel(app.responseGraph, 't')
            ylabel(app.responseGraph, 'f(t)')
            zlabel(app.responseGraph, 'Z')
            app.ZPAxis = uiaxes(app.ControllerTab);
            title(app.ZPAxis, 'Poles-Zeros')
            xlabel(app.ZPAxis, 'σ')
            ylabel(app.ZPAxis, 'Jw')
            zlabel(app.ZPAxis, 'Z')
            app.SecondOrderCharacteristicsPanel_2 = uipanel(app.ControllerTab);
            app.SecondOrderCharacteristicsPanel_2.Title = 'Second Order Characteristics';
            app.NaturalFrequencySlider_2Label = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.NaturalFrequencySlider_2Label.Text = 'Natural Frequency';
            app.NaturalFrequencySlider_PID = uislider(app.SecondOrderCharacteristicsPanel_2);
            app.NaturalFrequencySlider_PID.Limits = [0 50];
            app.NaturalFrequencySlider_PID.ValueChangedFcn = createCallbackFcn(app, @NaturalFrequencySlider_PIDValueChanged, true);
            app.WnRadSecEditFieldLabel_2 = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.WnRadSecEditFieldLabel_2.Text = 'Wn(Rad/Sec)';
            app.Wn_PID = uieditfield(app.SecondOrderCharacteristicsPanel_2, 'numeric');
            app.Wn_PID.ValueChangedFcn = createCallbackFcn(app, @Wn_PIDValueChanged, true);
            app.DampingRatioSlider_2Label = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.DampingRatioSlider_2Label.Text = 'Damping Ratio';
            app.DampingRatioSlider_PID = uislider(app.SecondOrderCharacteristicsPanel_2);
            app.DampingRatioSlider_PID.Limits = [-20 20];
            app.DampingRatioSlider_PID.ValueChangedFcn = createCallbackFcn(app, @DampingRatioSlider_PIDValueChanged, true);
            app.Label_3 = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.Label_3.Text = 'ζ';
            app.zeta_PID = uieditfield(app.SecondOrderCharacteristicsPanel_2, 'numeric');
            app.zeta_PID.ValueChangedFcn = createCallbackFcn(app, @zeta_PIDValueChanged, true);
            app.RaiseTimeSecLabel_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.RaiseTimeSecLabel_PID.Text = 'Raise Time (Sec)';
            app.SettlingTimeSecLabel_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.SettlingTimeSecLabel_PID.Text = 'Settling Time (Sec)';
            app.TimeConstantSecLabel_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.TimeConstantSecLabel_PID.Text = 'Time Constant (Sec)';
            app.OvershootLabel_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.OvershootLabel_PID.Text = 'Overshoot %';
            app.raiseTime_IP_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.raiseTime_IP_PID.Text = 'NaN';
            app.settlingTime_IP_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.settlingTime_IP_PID.Text = 'NaN';
            app.TimeConstant_IP_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.TimeConstant_IP_PID.Text = 'NaN';
            app.oversoot_IP_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.oversoot_IP_PID.Text = 'NaN';
            app.SystemTypeLabel_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.SystemTypeLabel_PID.Text = 'System Type';
            app.systemType_IP_PID = uilabel(app.SecondOrderCharacteristicsPanel_2);
            app.systemType_IP_PID.Text = 'NaN';
            app.PIDTunningPanel = uipanel(app.ControllerTab);
            app.PIDTunningPanel.Title = 'PID Tunning';
            app.PControllerSliderLabel = uilabel(app.PIDTunningPanel);
            app.PControllerSliderLabel.Text = 'P-Controller';
            app.P_ControllerSlider = uislider(app.PIDTunningPanel);
            app.P_ControllerSlider.Limits = [-10 100];
            app.P_ControllerSlider.ValueChangedFcn = createCallbackFcn(app, @P_ControllerSliderValueChanged, true);
            app.IControllerSliderLabel = uilabel(app.PIDTunningPanel);
            app.IControllerSliderLabel.Text = 'I-Controller';
            app.I_ControllerSlider = uislider(app.PIDTunningPanel);
            app.I_ControllerSlider.Limits = [-10 100];
            app.I_ControllerSlider.ValueChangedFcn = createCallbackFcn(app, @I_ControllerSliderValueChanged, true);
            app.DControllerSliderLabel = uilabel(app.PIDTunningPanel);
            app.DControllerSliderLabel.Text = 'D-Controller';
            app.D_ControllerSlider = uislider(app.PIDTunningPanel);
            app.D_ControllerSlider.Limits = [-10 100];
            app.D_ControllerSlider.ValueChangedFcn = createCallbackFcn(app, @D_ControllerSliderValueChanged, true);
            app.PControllerEditFieldLabel = uilabel(app.PIDTunningPanel);
            app.PControllerEditFieldLabel.Text = 'P-Controller';
            app.P_Controller = uieditfield(app.PIDTunningPanel, 'numeric');
            app.P_Controller.ValueChangedFcn = createCallbackFcn(app, @P_ControllerValueChanged, true);
            app.P_Controller.Value = 1;
            app.IControllerEditFieldLabel = uilabel(app.PIDTunningPanel);
            app.IControllerEditFieldLabel.Text = 'I-Controller';
            app.I_Controller = uieditfield(app.PIDTunningPanel, 'numeric');
            app.I_Controller.ValueChangedFcn = createCallbackFcn(app, @I_ControllerValueChanged, true);
            app.DControllerEditFieldLabel = uilabel(app.PIDTunningPanel);
            app.DControllerEditFieldLabel.Text = 'D-Controller';
            app.D_Controller = uieditfield(app.PIDTunningPanel, 'numeric');
            app.D_Controller.ValueChangedFcn = createCallbackFcn(app, @D_ControllerValueChanged, true);
            app.DesiredZetaLabel = uilabel(app.ControllerTab);
            app.DesiredZetaLabel.Text = 'Desired Zeta';
            app.ZetaDesired = uilabel(app.ControllerTab);
            app.ZetaDesired.Text = '';
            app.WnDesired = uilabel(app.ControllerTab);
            app.WnDesired.Text = '';
            app.DesiredWnLabel = uilabel(app.ControllerTab);
            app.DesiredWnLabel.Text = 'Desired Wn';
            app.AutomaticTab = uitab(app.TabGroup);
            app.AutomaticTab.Title = 'Automatic';
            app.secon_order_plot = uiaxes(app.AutomaticTab);
            title(app.secon_order_plot, 'open loop ')
            xlabel(app.secon_order_plot, 'X')
            ylabel(app.secon_order_plot, 'Y')
            app.pid_closed_loop_plot = uiaxes(app.AutomaticTab);
            title(app.pid_closed_loop_plot, 'pid closed loop')
            xlabel(app.pid_closed_loop_plot, 'X')
            ylabel(app.pid_closed_loop_plot, 'Y')
            app.Image6 = uiimage(app.AutomaticTab);
            app.Image6.ImageSource = 'closed.jpg';
            app.OpenLoopLabel = uilabel(app.AutomaticTab);
            app.OpenLoopLabel.Text = 'Open Loop';
            app.ClosedLoopLabel = uilabel(app.AutomaticTab);
            app.ClosedLoopLabel.Text = 'Closed Loop';
            app.boEditFieldLabel = uilabel(app.AutomaticTab);
            app.boEditFieldLabel.Text = 'bo';
            app.bo = uieditfield(app.AutomaticTab, 'numeric');
            app.bo.ValueChangedFcn = createCallbackFcn(app, @boValueChanged, true);
            app.a1Label = uilabel(app.AutomaticTab);
            app.a1Label.Text = 'a1';
            app.a1 = uieditfield(app.AutomaticTab, 'numeric');
            app.a1.ValueChangedFcn = createCallbackFcn(app, @a1ValueChanged, true);
            app.a2Label = uilabel(app.AutomaticTab);
            app.a2Label.Text = 'a2';
            app.a2 = uieditfield(app.AutomaticTab, 'numeric');
            app.a2.ValueChangedFcn = createCallbackFcn(app, @a2ValueChanged, true);
            app.DesiredWoLabel = uilabel(app.AutomaticTab);
            app.DesiredWoLabel.Text = 'Desired Wo';
            app.wo = uieditfield(app.AutomaticTab, 'numeric');
            app.wo.ValueChangedFcn = createCallbackFcn(app, @woValueChanged, true);
            app.DesiredLabel = uilabel(app.AutomaticTab);
            app.DesiredLabel.Text = 'Desired 𝜁';
            app.zeta_m = uieditfield(app.AutomaticTab, 'numeric');
            app.zeta_m.ValueChangedFcn = createCallbackFcn(app, @zeta_mValueChanged, true);
            app.TiLabel = uilabel(app.AutomaticTab);
            app.TiLabel.Text = 'Ti = ';
            app.TdLabel = uilabel(app.AutomaticTab);
            app.TdLabel.Text = 'Td =';
            app.KLabel = uilabel(app.AutomaticTab);
            app.KLabel.Text = 'K = ';
            app.Td = uilabel(app.AutomaticTab);
            app.Td.Text = ' ';
            app.K = uilabel(app.AutomaticTab);
            app.K.Text = ' ';
            app.Ti = uilabel(app.AutomaticTab);
            app.Ti.Text = ' ';
            app.Image5 = uiimage(app.AutomaticTab);
            app.Image5.ImageSource = 'index.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = secondOrderSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end